classdef GraphicEqualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        StopPlaybackButton     matlab.ui.control.Button
        PlayEqualizedButton    matlab.ui.control.Button
        PlayOriginalButton     matlab.ui.control.Button
        SaveAudioButton        matlab.ui.control.Button
        Label_7                matlab.ui.control.Label
        BackToWorkbenchButton  matlab.ui.control.Button
        Gain16kLabel           matlab.ui.control.Label
        Gain8kLabel            matlab.ui.control.Label
        Gain4kLabel            matlab.ui.control.Label
        Gain2kLabel            matlab.ui.control.Label
        Gain1kLabel            matlab.ui.control.Label
        Gain500Label           matlab.ui.control.Label
        Gain250Label           matlab.ui.control.Label
        Gain125Label           matlab.ui.control.Label
        Gain62Label            matlab.ui.control.Label
        Slider16k              matlab.ui.control.Slider
        Slider8k               matlab.ui.control.Slider
        Slider4k               matlab.ui.control.Slider
        Slider2k               matlab.ui.control.Slider
        Slider1k               matlab.ui.control.Slider
        Slider500              matlab.ui.control.Slider
        Slider250              matlab.ui.control.Slider
        Slider125              matlab.ui.control.Slider
        Label_6                matlab.ui.control.Label
        CurrentSongLabel       matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        kHzLabel_5             matlab.ui.control.Label
        kHzLabel_4             matlab.ui.control.Label
        kHzLabel_3             matlab.ui.control.Label
        kHzLabel_2             matlab.ui.control.Label
        kHzLabel               matlab.ui.control.Label
        HzLabel_5              matlab.ui.control.Label
        HzLabel_4              matlab.ui.control.Label
        HzLabel_3              matlab.ui.control.Label
        HzLabel_2              matlab.ui.control.Label
        GridLayout2            matlab.ui.container.GridLayout
        ProcessedSpectrumAxes  matlab.ui.control.UIAxes
        OriginalSpectrumAxes   matlab.ui.control.UIAxes
        EqualizerSwitch        matlab.ui.control.Switch
        Slider62               matlab.ui.control.Slider
        Label                  matlab.ui.control.Label
    end

    properties (Access = private)
        OriginalAudio = []
        ProcessedAudio = []
        SampleRate = []
        CurrentSongName = ''
        SourceFilePath = ''
        HasAudio = false
        IsEQEnabled = false
        MainApp = []
        CallbacksInitialized = false
        PlaybackPlayer = []
        IsProcessed = false
    end

    methods (Access = private)

        function setDefaultEqualizerValues(app)

            % 明显但仍适合正常试听的默认均衡曲线。
            app.Slider62.Value = 5.0;
            app.Slider125.Value = 4.0;
            app.Slider250.Value = 2.0;
            app.Slider500.Value = -2.0;
            app.Slider1k.Value = -4.0;
            app.Slider2k.Value = -1.0;
            app.Slider4k.Value = 3.0;
            app.Slider8k.Value = 5.0;
            app.Slider16k.Value = 4.0;

            updateGainValueLabels(app);


        end

        function initializeUserInterface(app)

            if app.CallbacksInitialized
                return;
            end

            sliders = { ...
                app.Slider62, ...
                app.Slider125, ...
                app.Slider250, ...
                app.Slider500, ...
                app.Slider1k, ...
                app.Slider2k, ...
                app.Slider4k, ...
                app.Slider8k, ...
                app.Slider16k};

            for k = 1:numel(sliders)

                sliders{k}.ValueChangingFcn = ...
                    @(src, event)gainValueChanging(app, src, event);

                sliders{k}.ValueChangedFcn = ...
                    @(src, event)gainValueChanged(app, src, event);
            end

            app.EqualizerSwitch.ValueChangedFcn = ...
                @(src, event)equalizerSwitchChanged(app, src, event);

            app.SaveAudioButton.ButtonPushedFcn = ...
                @(src, event)saveAudio(app, src, event);

            app.BackToWorkbenchButton.ButtonPushedFcn = ...
                @(src, event)returnToWorkbench(app, src, event);

            app.PlayOriginalButton.ButtonPushedFcn = ...
                @(src, event)playOriginalAudio(app, src, event);

            app.PlayEqualizedButton.ButtonPushedFcn = ...
                @(src, event)playEqualizedAudio(app, src, event);

            app.StopPlaybackButton.ButtonPushedFcn = ...
                @(src, event)stopPlayback(app, src, event);

            % 初始状态下尚未载入音频，也没有均衡结果。
            app.IsProcessed = false;
            app.PlayEqualizedButton.Enable = 'off';
            app.SaveAudioButton.Enable = 'off';

            app.CallbacksInitialized = true;

            updateGainValueLabels(app);


        end

        function playOriginalAudio(app, ~, ~)


            if ~app.HasAudio || ...
                    isempty(app.OriginalAudio) || ...
                    isempty(app.SampleRate)

                uialert(app.UIFigure, ...
                    '请先在主工作台中载入音频。', ...
                    '无法播放', ...
                    'Icon', 'warning');

                return;
            end

            stopPlayback(app);

            try
                app.PlaybackPlayer = audioplayer( ...
                    app.OriginalAudio, ...
                    app.SampleRate);

                play(app.PlaybackPlayer);

            catch ME
                app.PlaybackPlayer = [];

                uialert(app.UIFigure, ...
                    ME.message, ...
                    '原声播放失败', ...
                    'Icon', 'error');
            end

        end

        function gainValueChanging(app, source, event)

            updateOneGainLabel(app, source, event.Value);

            % 滑块正在改变，已有处理结果已经过期。
            app.IsProcessed = false;
            app.PlayEqualizedButton.Enable = 'off';
        end

        function gainValueChanged(app, source, event)

            updateOneGainLabel(app, source, source.Value);
            if app.IsEQEnabled && app.HasAudio
                refreshProcessedAudio(app);
            end
            
        end

        function playEqualizedAudio(app, ~, ~)
            if ~app.HasAudio || ...
                    isempty(app.OriginalAudio) || ...
                    isempty(app.SampleRate)

                uialert( ...
                    app.UIFigure, ...
                    '请先在主工作台中载入音频。', ...
                    '无法播放', ...
                    'Icon', ...
                    'warning');

                return;
            end

            if ~app.IsEQEnabled
                uialert( ...
                    app.UIFigure, ...
                    '请先打开均衡器。', ...
                    '均衡器未打开', ...
                    'Icon', ...
                    'warning');

                return;
            end

            if ~app.IsProcessed || isempty(app.ProcessedAudio)
                uialert( ...
                    app.UIFigure, ...
                    '均衡音频尚未处理完成，请稍后再播放。', ...
                    '尚未完成处理', ...
                    'Icon', ...
                    'warning');

                return;
            end

            stopPlayback(app);

            try
                app.PlaybackPlayer = audioplayer( ...
                    app.ProcessedAudio, ...
                    app.SampleRate);

                play(app.PlaybackPlayer);

            catch ME
                app.PlaybackPlayer = [];

                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    '均衡音频播放失败', ...
                    'Icon', ...
                    'error');
            end


        end

        function stopPlayback(app, varargin)

            % 当前没有播放器对象时，不需要执行停止操作。
            if isempty(app.PlaybackPlayer)
                return;
            end

            try
                % 播放器仍然有效时停止播放。
                if isvalid(app.PlaybackPlayer)
                    stop(app.PlaybackPlayer);
                end
            catch
                % 即使播放器对象已经失效，也继续清空引用，
                % 避免旧播放器影响下一次播放。
            end

            app.PlaybackPlayer = [];

        end

        function equalizerSwitchChanged(app, source, event)

            app.IsEQEnabled = source.ValueIndex == 2;

            if ~app.HasAudio
                app.IsProcessed = false;
                app.PlayEqualizedButton.Enable = 'off';

                uialert( ...
                    app.UIFigure, ...
                    '请先在主工作台中载入音频。', ...
                    '尚未载入音频', ...
                    'Icon', ...
                    'warning');

                return;
            end

            if app.IsEQEnabled
                % 打开均衡器后，使用当前九段参数重新处理音频。
                refreshProcessedAudio(app);
            else
                % 关闭均衡器后，不允许播放“均衡音频”。
                app.IsProcessed = false;
                app.ProcessedAudio = app.OriginalAudio;
                app.PlayEqualizedButton.Enable = 'off';

                % 右侧频谱恢复显示原始音频频谱。
                try
                    plotSpectrum( ...
                        app, ...
                        app.ProcessedSpectrumAxes, ...
                        app.OriginalAudio);
                catch ME
                    uialert( ...
                        app.UIFigure, ...
                        ME.message, ...
                        '频谱刷新失败', ...
                        'Icon', ...
                        'error');
                end
            end
        end

        function updateGainValueLabels(app)
            sliders = {app.Slider62, app.Slider125, app.Slider250, app.Slider500, ...
                app.Slider1k, app.Slider2k, app.Slider4k, app.Slider8k, app.Slider16k};
            labels = {app.Gain62Label, app.Gain125Label, app.Gain250Label, app.Gain500Label, ...
                app.Gain1kLabel, app.Gain2kLabel, app.Gain4kLabel, app.Gain8kLabel, app.Gain16kLabel};
            for k = 1:numel(sliders)
                labels{k}.Text = formatGainValue(app, sliders{k}.Value);
            end
        end

        function updateOneGainLabel(app, source, value)
            sliders = {app.Slider62, app.Slider125, app.Slider250, app.Slider500, ...
                app.Slider1k, app.Slider2k, app.Slider4k, app.Slider8k, app.Slider16k};
            labels = {app.Gain62Label, app.Gain125Label, app.Gain250Label, app.Gain500Label, ...
                app.Gain1kLabel, app.Gain2kLabel, app.Gain4kLabel, app.Gain8kLabel, app.Gain16kLabel};
            for k = 1:numel(sliders)
                if isequal(source, sliders{k})
                    labels{k}.Text = formatGainValue(app, value);
                    return;
                end
            end
        end

        function textValue = formatGainValue(app, value)
            if abs(value) < 0.05
                textValue = '0.0dB';
            elseif value > 0
                textValue = sprintf('+%.1fdB', value);
            else
                textValue = sprintf('%.1fdB', value);
            end
        end

        function gains = getBandGainsDB(app)
            gains = [app.Slider62.Value app.Slider125.Value app.Slider250.Value ...
                app.Slider500.Value app.Slider1k.Value app.Slider2k.Value ...
                app.Slider4k.Value app.Slider8k.Value app.Slider16k.Value];
        end

        function outputAudio = applyGraphicEqualizer(app, inputAudio)

            if isempty(inputAudio)
                error('输入音频为空，无法执行均衡处理。');
            end

            if isempty(app.SampleRate) || ...
                    ~isscalar(app.SampleRate) || ...
                    app.SampleRate <= 0

                error('采样率无效，无法执行均衡处理。');
            end

            % 读取九个滑块的dB增益。
            gainsDB = getBandGainsDB(app);

            % 把dB增益转换成线性倍数。
            linearGains = 10 .^ (gainsDB / 20);

            % 设计九段滤波器组。
            filters = designFilterBank(app);

            % 使用零矩阵保存所有频段叠加后的结果。
            outputAudio = zeros(size(inputAudio));

            validBandCount = 0;

            for k = 1:numel(filters)

                if isempty(filters{k})
                    continue;
                end

                coefficients = filters{k};
                b = coefficients{1};
                a = coefficients{2};

                % 从原始音频中提取当前频段。
                bandAudio = filter(b, a, inputAudio);

                % 对当前频段应用对应滑块的增益。
                bandAudio = linearGains(k) .* bandAudio;

                % 将处理后的频段加入最终结果。
                outputAudio = outputAudio + bandAudio;

                validBandCount = validBandCount + 1;
            end

            if validBandCount == 0
                error('没有成功生成任何有效均衡频段。');
            end

            % 去除滤波器启动阶段可能产生的直流偏移。
            outputAudio = outputAudio - mean(outputAudio, 1);

            % 防止输出超过[-1,1]而削波失真。
            peakValue = max(abs(outputAudio(:)));

            if peakValue > 0.98
                outputAudio = outputAudio .* (0.98 / peakValue);
            end

            % 检查结果是否合法。
            if any(~isfinite(outputAudio(:)))
                error('均衡处理结果包含NaN或Inf。');
            end
        end

        function filters = designFilterBank(app)

            fs = app.SampleRate;
            nyquist = fs / 2;

            if nyquist <= 20
                error('音频采样率过低，无法设计九段均衡器。');
            end

            % 九段均衡器的中心频率。
            centers = [62 125 250 500 1000 2000 4000 8000 16000];

            % 相邻中心频率的几何平均值作为频段分界。
            boundaries = sqrt(centers(1:end-1) .* centers(2:end));

            filters = cell(1, 9);

            try
                %% 第一段：低通
                firstHigh = min(boundaries(1), nyquist * 0.98);

                if firstHigh > 1
                    [b, a] = butter(3, firstHigh / nyquist, 'low');
                    filters{1} = {b, a};
                end

                %% 第二段到第八段：带通
                for k = 2:8

                    lowEdge = boundaries(k - 1);
                    highEdge = boundaries(k);

                    if lowEdge >= nyquist * 0.98
                        continue;
                    end

                    highEdge = min(highEdge, nyquist * 0.98);

                    if highEdge <= lowEdge
                        continue;
                    end

                    normalizedBand = [lowEdge highEdge] / nyquist;

                    [b, a] = butter(3, normalizedBand, 'bandpass');

                    filters{k} = {b, a};
                end

                %% 第九段：高通
                lastLow = boundaries(end);

                if lastLow < nyquist * 0.98
                    [b, a] = butter(3, lastLow / nyquist, 'high');
                    filters{9} = {b, a};
                else
                    % 采样率较低时，16kHz频段无法存在。
                    filters{9} = [];
                end

            catch ME
                error( ...
                    'GraphicEqualizer:FilterDesignFailed', ...
                    '九段滤波器设计失败：%s', ...
                    ME.message);
            end
        end

        function refreshProcessedAudio(app)

            previousAudio = app.ProcessedAudio;

            % 开始处理时，旧结果立即失效。
            app.IsProcessed = false;
            app.PlayEqualizedButton.Enable = 'off';
            app.SaveAudioButton.Enable = 'off';

            try
                if ~app.HasAudio || ...
                        isempty(app.OriginalAudio) || ...
                        isempty(app.SampleRate)

                    error('当前没有可以处理的音频。');
                end

                if ~app.IsEQEnabled
                    app.ProcessedAudio = app.OriginalAudio;

                    plotSpectrum( ...
                        app, ...
                        app.ProcessedSpectrumAxes, ...
                        app.ProcessedAudio);

                    return;
                end

                candidate = applyGraphicEqualizer( ...
                    app, ...
                    app.OriginalAudio);

                if isempty(candidate)
                    error('均衡处理结果为空。');
                end

                if any(~isfinite(candidate(:)))
                    error('均衡处理结果包含无效数值。');
                end

                if ~isequal(size(candidate), size(app.OriginalAudio))
                    error('均衡处理结果的尺寸与原始音频不一致。');
                end

                app.ProcessedAudio = candidate;

                plotSpectrum( ...
                    app, ...
                    app.ProcessedSpectrumAxes, ...
                    app.ProcessedAudio);

                % 只有处理和频谱绘制全部成功，才标记处理完成。
                app.IsProcessed = true;
                app.PlayEqualizedButton.Enable = 'on';
                app.SaveAudioButton.Enable = 'on';

            catch ME
                app.ProcessedAudio = previousAudio;
                app.IsProcessed = false;
                app.PlayEqualizedButton.Enable = 'off';
                app.SaveAudioButton.Enable = 'off';

                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    '均衡处理失败', ...
                    'Icon', ...
                    'error');
            end
        end

        function plotSpectrum(app, axesHandle, audioData)
            try
                cla(axesHandle);
                if isempty(audioData) || isempty(app.SampleRate)
                    return;
                end
                maxSamples = min(size(audioData, 1), 65536);
                segment = audioData(1:maxSamples, :);
                sampleCount = size(segment, 1);
                if sampleCount < 2
                    return;
                end
                window = 0.5 - 0.5 .* cos(2 .* pi .* (0:sampleCount-1)' ./ (sampleCount-1));
                nfft = 2^nextpow2(sampleCount);
                spectrum = fft(segment .* repmat(window, 1, size(segment, 2)), nfft, 1);
                magnitude = mean(abs(spectrum(1:nfft/2+1, :)), 2) ./ max(sum(window), eps);
                frequency = (0:nfft/2)' .* app.SampleRate ./ nfft;
                valid = frequency >= 20 & frequency <= min(20000, app.SampleRate / 2);
                plot(axesHandle, frequency(valid), 20 .* log10(magnitude(valid) + eps), 'LineWidth', 1);
                axesHandle.XScale = 'log';
                axesHandle.XLim = [20 max(20.1, min(20000, app.SampleRate / 2))];
                grid(axesHandle, 'on');
            catch ME
                cla(axesHandle);
                error('GraphicEqualizer:SpectrumFailed', '频谱绘制失败：%s', ME.message);
            end
        end

        function saveAudio(app, source, event)

            if ~app.HasAudio || ...
                    isempty(app.OriginalAudio) || ...
                    isempty(app.SampleRate)

                uialert( ...
                    app.UIFigure, ...
                    '请先在主工作台中载入音频。', ...
                    '无法保存', ...
                    'Icon', ...
                    'warning');

                return;
            end

            if ~app.IsEQEnabled
                uialert( ...
                    app.UIFigure, ...
                    '请先打开均衡器并完成均衡处理。', ...
                    '均衡器未打开', ...
                    'Icon', ...
                    'warning');

                return;
            end

            if ~app.IsProcessed || isempty(app.ProcessedAudio)
                uialert( ...
                    app.UIFigure, ...
                    '当前均衡音频尚未处理完成，无法保存。', ...
                    '尚未完成处理', ...
                    'Icon', ...
                    'warning');

                return;
            end

            try
                [~, baseName, ~] = fileparts(app.CurrentSongName);

                if isempty(baseName)
                    baseName = 'audio';
                end

                defaultFileName = [baseName '_EQ.wav'];

                [fileName, pathName] = uiputfile( ...
                    {'*.wav', 'WAV音频文件 (*.wav)'}, ...
                    '保存均衡器音频', ...
                    defaultFileName);

                if isequal(fileName, 0) || isequal(pathName, 0)
                    return;
                end

                audioData = app.ProcessedAudio;

                if isempty(audioData)
                    error('均衡处理结果为空，无法保存。');
                end

                if any(~isfinite(audioData(:)))
                    error('均衡处理结果包含NaN或Inf，无法保存。');
                end

                fullPath = fullfile(pathName, fileName);

                audiowrite( ...
                    fullPath, ...
                    audioData, ...
                    app.SampleRate);

                uialert( ...
                    app.UIFigure, ...
                    ['均衡音频已保存至：' newline fullPath], ...
                    '保存成功', ...
                    'Icon', ...
                    'success');

            catch ME
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    '保存失败', ...
                    'Icon', ...
                    'error');
            end
        end

        function returnToWorkbench(app, source, event)
            try
                if ~isempty(app.MainApp) && isvalid(app.MainApp) && ...
                        isprop(app.MainApp, 'UIFigure') && isvalid(app.MainApp.UIFigure)
                    app.MainApp.UIFigure.Visible = 'on';
                    figure(app.MainApp.UIFigure);
                    app.UIFigure.Visible = 'off';
                else
                    uialert(app.UIFigure, '主工作台当前不可用。', '无法返回', 'Icon', 'warning');
                end
            catch ME
                uialert(app.UIFigure, ME.message, '无法返回工作台', 'Icon', 'error');
            end
        end
    end

    methods (Access = public)
        function setMainApp(app, mainApp)
            app.MainApp = mainApp;
            initializeUserInterface(app);
        end

        function loadAudioFromMainApp(app, audioData, sampleRate, songName, varargin)
            try
                initializeUserInterface(app);
                if nargin >= 5
                    app.MainApp = varargin{1};
                end
                if isempty(audioData) || ~isnumeric(audioData) || ndims(audioData) > 2
                    error('音频必须是非空的二维数值矩阵。');
                end
                if ~isscalar(sampleRate) || ~isfinite(sampleRate) || sampleRate <= 0
                    error('采样率必须为正的有限标量。');
                end
                if size(audioData, 2) > 2
                    error('仅支持单声道或双声道音频。');
                end
                audioData = double(audioData);
                if isrow(audioData)
                    audioData = audioData(:);
                end
                if any(~isfinite(audioData(:)))
                    error('音频包含 NaN 或 Inf。');
                end
                if nargin < 4 || isempty(songName)
                    songName = '未命名音频';
                end
                app.OriginalAudio = audioData;
                app.ProcessedAudio = audioData;
                app.SampleRate = double(sampleRate);
                app.CurrentSongName = char(songName);
                app.SourceFilePath = '';
                if nargin >= 6 && ~isempty(varargin{2})
                    app.SourceFilePath = char(varargin{2});
                end
                app.HasAudio = true;

                % 设置默认九段均衡参数
                setDefaultEqualizerValues(app);

                % 默认打开均衡器
                app.IsEQEnabled = true;
                app.EqualizerSwitch.ValueIndex = 2;

                % 处理还没有完成
                app.IsProcessed = false;
                app.PlayEqualizedButton.Enable = 'off';

                app.CurrentSongLabel.Text = app.CurrentSongName;

                % 绘制原始频谱
                plotSpectrum(app, ...
                    app.OriginalSpectrumAxes, ...
                    app.OriginalAudio);

                % 自动执行一次均衡处理
                refreshProcessedAudio(app);
            catch ME
                uialert(app.UIFigure, ME.message, '载入音频失败', 'Icon', 'error');
                return;
            end
        end

        function show(app)
            initializeUserInterface(app);
            if ~isempty(app.UIFigure) && isvalid(app.UIFigure)
                app.UIFigure.Visible = 'on';
                figure(app.UIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '九段图示均衡器';
            app.Slider62 = uislider(app.GridLayout);
            app.Slider62.Limits = [-12 12];
            app.Slider62.MajorTicks = [-12 -6 0 6 12];
            app.Slider62.MinorTicksMode = 'manual';
            app.EqualizerSwitch = uiswitch(app.GridLayout, 'slider');
            app.EqualizerSwitch.Items = {'', ''};
            app.EqualizerSwitch.Value = '';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OriginalSpectrumAxes = uiaxes(app.GridLayout2);
            title(app.OriginalSpectrumAxes, '均衡调节前')
            xlabel(app.OriginalSpectrumAxes, '频率/Hz')
            ylabel(app.OriginalSpectrumAxes, '幅度/dB')
            zlabel(app.OriginalSpectrumAxes, 'Z')
            app.ProcessedSpectrumAxes = uiaxes(app.GridLayout2);
            title(app.ProcessedSpectrumAxes, '均衡调节后')
            xlabel(app.ProcessedSpectrumAxes, '频率/Hz')
            ylabel(app.ProcessedSpectrumAxes, '幅度/dB')
            zlabel(app.ProcessedSpectrumAxes, 'Z')
            app.HzLabel_2 = uilabel(app.GridLayout);
            app.HzLabel_2.Text = '125Hz';
            app.HzLabel_3 = uilabel(app.GridLayout);
            app.HzLabel_3.Text = '62Hz';
            app.HzLabel_4 = uilabel(app.GridLayout);
            app.HzLabel_4.Text = '250Hz';
            app.HzLabel_5 = uilabel(app.GridLayout);
            app.HzLabel_5.Text = '500Hz';
            app.kHzLabel = uilabel(app.GridLayout);
            app.kHzLabel.Text = '1kHz';
            app.kHzLabel_2 = uilabel(app.GridLayout);
            app.kHzLabel_2.Text = '2kHz';
            app.kHzLabel_3 = uilabel(app.GridLayout);
            app.kHzLabel_3.Text = '4kHz';
            app.kHzLabel_4 = uilabel(app.GridLayout);
            app.kHzLabel_4.Text = '8kHz';
            app.kHzLabel_5 = uilabel(app.GridLayout);
            app.kHzLabel_5.Text = '16kHz';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '当前歌曲：';
            app.CurrentSongLabel = uilabel(app.GridLayout);
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '打开均衡';
            app.Slider125 = uislider(app.GridLayout);
            app.Slider125.Limits = [-12 12];
            app.Slider125.MajorTicks = [-12 -6 0 6 12];
            app.Slider125.MinorTicksMode = 'manual';
            app.Slider250 = uislider(app.GridLayout);
            app.Slider250.Limits = [-12 12];
            app.Slider250.MajorTicks = [-12 -6 0 6 12];
            app.Slider250.MinorTicksMode = 'manual';
            app.Slider500 = uislider(app.GridLayout);
            app.Slider500.Limits = [-12 12];
            app.Slider500.MajorTicks = [-12 -6 0 6 12];
            app.Slider500.MinorTicksMode = 'manual';
            app.Slider1k = uislider(app.GridLayout);
            app.Slider1k.Limits = [-12 12];
            app.Slider1k.MajorTicks = [-12 -6 0 6 12];
            app.Slider1k.MinorTicksMode = 'manual';
            app.Slider2k = uislider(app.GridLayout);
            app.Slider2k.Limits = [-12 12];
            app.Slider2k.MajorTicks = [-12 -6 0 6 12];
            app.Slider2k.MinorTicksMode = 'manual';
            app.Slider4k = uislider(app.GridLayout);
            app.Slider4k.Limits = [-12 12];
            app.Slider4k.MajorTicks = [-12 -6 0 6 12];
            app.Slider4k.MinorTicksMode = 'manual';
            app.Slider8k = uislider(app.GridLayout);
            app.Slider8k.Limits = [-12 12];
            app.Slider8k.MajorTicks = [-12 -6 0 6 12];
            app.Slider8k.MinorTicksMode = 'manual';
            app.Slider16k = uislider(app.GridLayout);
            app.Slider16k.Limits = [-12 12];
            app.Slider16k.MajorTicks = [-12 -6 0 6 12];
            app.Slider16k.MinorTicksMode = 'manual';
            app.Gain62Label = uilabel(app.GridLayout);
            app.Gain62Label.Text = '0.0dB';
            app.Gain125Label = uilabel(app.GridLayout);
            app.Gain125Label.Text = '0.0dB';
            app.Gain250Label = uilabel(app.GridLayout);
            app.Gain250Label.Text = '0.0dB';
            app.Gain500Label = uilabel(app.GridLayout);
            app.Gain500Label.Text = '0.0dB';
            app.Gain1kLabel = uilabel(app.GridLayout);
            app.Gain1kLabel.Text = '0.0dB';
            app.Gain2kLabel = uilabel(app.GridLayout);
            app.Gain2kLabel.Text = '0.0dB';
            app.Gain4kLabel = uilabel(app.GridLayout);
            app.Gain4kLabel.Text = '0.0dB';
            app.Gain8kLabel = uilabel(app.GridLayout);
            app.Gain8kLabel.Text = '0.0dB';
            app.Gain16kLabel = uilabel(app.GridLayout);
            app.Gain16kLabel.Text = '0.0dB';
            app.BackToWorkbenchButton = uibutton(app.GridLayout, 'push');
            app.BackToWorkbenchButton.Text = '返回工作台';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '关闭均衡';
            app.SaveAudioButton = uibutton(app.GridLayout, 'push');
            app.SaveAudioButton.Text = '保存音频';
            app.PlayOriginalButton = uibutton(app.GridLayout, 'push');
            app.PlayOriginalButton.Text = '播放原声';
            app.PlayEqualizedButton = uibutton(app.GridLayout, 'push');
            app.PlayEqualizedButton.Text = '播放均衡';
            app.StopPlaybackButton = uibutton(app.GridLayout, 'push');
            app.StopPlaybackButton.Text = '暂停播放';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphicEqualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end